classdef Affine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AffineTransformationUIFigure  matlab.ui.Figure
        AddparamstoBatchProcessingButton  matlab.ui.control.Button
        UndoonceButton                matlab.ui.control.Button
        ApplytocurrentButton          matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        RotationEditFieldLabel        matlab.ui.control.Label
        RotationEditField             matlab.ui.control.NumericEditField
        ApplytooriginalButton         matlab.ui.control.Button
        ResizeratioLabel              matlab.ui.control.Label
        TranslationpixelLabel         matlab.ui.control.Label
    end

    properties (Access = private)
        CallingApp   % Main app object
    end


    methods (Access = private)

        % Main functions for resize, rotate, and move
        % % Resize
        % function out = resize(app, mask)
        %     obd = evalin("base","obd");
        % 
        %     [r ,c]=size(mask);
        %     rs_mask = imresize(mask,[r*app.xEditField.Value, c*app.yEditField.Value]);
        %     [B, ~]  = bwboundaries(rs_mask);
        % 
        %     rs_bd = B{1};
        % 
        %     % old cener
        %     r0 = round(0.5*(max(obd(:,1))+min(obd(:,1))));
        %     c0 = round(0.5*(max(obd(:,2))+min(obd(:,2))));
        % 
        %     % new cener
        %     r1 = round(0.5*(max(rs_bd(:,1))+min(rs_bd(:,1))));
        %     c1 = round(0.5*(max(rs_bd(:,2))+min(rs_bd(:,2))));
        % 
        %     new_bd = zeros(size(rs_bd));
        %     % move back
        %     tras = [1 0 r0-r1; 0 1 c0-c1; 0 0 1];
        % 
        %     for i = 1:length(new_bd)
        %         p = [rs_bd(i,1); rs_bd(i,2); 1];
        %         p = tras*p;
        % 
        %         % make sure not exceed the image size
        %         x = p(1,1); y = p(2,1);
        %         if (x<=r) && (y<=c) && (x>=1) && (y>=1)
        %             new_bd(i,1)=x; new_bd(i,2)=y;
        %         end
        %     end
        % 
        %     out = new_bd;
        % end

        % Rotate
        % function out = rotate(app, boundary)
        %     omask = evalin("base","omask");
        %     new_bd = zeros(size(boundary));
        %     [r ,c]=size(omask);
        % 
        %     % ceners of rotation
        %     r0 = round(0.5*(max(boundary(:,1))+min(boundary(:,1))));
        %     c0 = round(0.5*(max(boundary(:,2))+min(boundary(:,2))));
        % 
        %     degree = app.RotationEditField.Value*pi / 180;
        % 
        %     % rotation by center point ------------------------
        % 
        %     % Shift X/Y to the rotation center
        %     tras1 = [1 0 -r0; 0 1 -c0; 0 0 1];
        %     % Rotate the coordinates
        %     tras2 = [cos(degree) -sin(degree) 0; sin(degree) cos(degree) 0; 0 0 1];
        %     % Shift the rotated coordinates back to the original reference center
        %     tras3 = [1 0 r0; 0 1 c0; 0 0 1];
        % 
        %     for i = 1:length(new_bd)
        %         p = [boundary(i,1); boundary(i,2); 1];
        %         p = tras3 * tras2 * tras1 * p;
        % 
        %         % make sure not exceed the image size
        %         x = p(1,1); y = p(2,1);
        %         if (x<=r) && (y<=c) && (x>=1) && (y>=1)
        %             new_bd(i,1)=x; new_bd(i,2)=y;
        %         end
        %     end
        % 
        %     out = new_bd;
        % end

        % Move/Shift
        % function out = move(app, boundary)
        %     omask = evalin("base","omask");
        % 
        %     new_bd = zeros(size(boundary));
        %     [r ,c]=size(omask);
        % 
        %     tras = [1 0 app.XEditField.Value; 0 1 app.YEditField.Value; 0 0 1];
        % 
        %     for i = 1:length(new_bd)
        %         p = [boundary(i,1); boundary(i,2); 1];
        %         p = tras*p;
        % 
        %         % make sure not exceed the image size
        %         x = p(1,1); y = p(2,1);
        %         if (x<=r) && (y<=c) && (x>=1) && (y>=1)
        %             new_bd(i,1)=x; new_bd(i,2)=y;
        %         end
        %     end
        % 
        %     out = new_bd;
        % end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
  
            app.UndoonceButton.Enable="off";

        end

        % Button pushed function: ApplytooriginalButton
        function ApplytooriginalButtonPushed(app, event)
            omask = evalin("base","omask");

            % Apply ATs
            resize = [app.xEditField.Value, app.yEditField.Value];
            rotate = app.RotationEditField.Value;
            shift = [app.XEditField.Value,app.YEditField.Value];
            bd = mod_affine(omask, resize, rotate, shift, true);
            

            % save last-time "bd" for _Undo_ if it exists
            try
                old_bd = evalin("base","bd");
                if ~isequal(old_bd,bd)
                    app.UndoonceButton.Enable = "on";
                    assignin("base","old_bd",old_bd); % public data
                end
            end

            assignin("base","bd",bd); % public data
            updateimage(app.CallingApp, bd)

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ApplytocurrentButton
        function ApplytocurrentButtonPushed(app, event)
            omask = evalin("base","omask");
            bd = evalin("base","bd");

            obd = evalin("base","obd");
            if isequal(bd,obd) % for original inputs in syn mode, no change
                mask = omask; % use original mask
            else
                mask = poly2mask(bd(:,2), bd(:,1),size(omask,1),size(omask,2));
            end
            
            % Apply ATs
            resize = [app.xEditField.Value, app.yEditField.Value];
            rotate = app.RotationEditField.Value;
            shift = [app.XEditField.Value,app.YEditField.Value];
            bd = mod_affine(mask, resize, rotate, shift, true);

            % save last-time "bd" for _Undo_ if it exists
            old_bd = evalin("base","bd");
            if ~isequal(old_bd,bd)
                app.UndoonceButton.Enable = "on";
                assignin("base","old_bd",old_bd); % public data
            end

            assignin("base","bd",bd); % public data
            updateimage(app.CallingApp, bd)
        end

        % Close request function: AffineTransformationUIFigure
        function AffineTransformationUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: UndoonceButton
        function UndoonceButtonPushed(app, event)
            % Restore last-time "bd" if it exists
            try
                bd = evalin("base","old_bd");
                assignin("base","bd",bd); % public data
                updateimage(app.CallingApp, bd)
                app.UndoonceButton.Enable = "off";
            end

        end

        % Button pushed function: AddparamstoBatchProcessingButton
        function AddparamstoBatchProcessingButtonPushed(app, event)
            % Check if the window is opening
            if evalin("base","Batch_window_opening")==true
                params = struct;
                params.categ = "AF";
                params.af_resize_x = app.xEditField.Value;
                params.af_resize_y = app.yEditField.Value;
                params.af_shift_x = app.XEditField.Value;
                params.af_shift_y = app.YEditField.Value;
                params.af_rotation = app.RotationEditField.Value;

                params.fd_detail_p = NaN;
                params.fd_range_p = NaN;
                params.fd_mag = NaN;

                params.sp_center = NaN;
                params.sp_height = NaN;
                params.sp_width = NaN;

                list = evalin("base","list");

                % check if empty list
                if isempty(fieldnames(list))
                    list.row(1) = params;
                else
                    list.row(end+1) = params;
                end

                assignin ("base","list",list)
                refrashList(app.CallingApp.BatchApp)

            else
                uialert(app.AffineTransformationUIFigure, "The Batch Processing window is not opening", "Error");
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AffineTransformationUIFigure = uifigure('Visible', 'off');
            app.AffineTransformationUIFigure.Name = 'AffineTransformation';
            app.AffineTransformationUIFigure.CloseRequestFcn = createCallbackFcn(app, @AffineTransformationUIFigureCloseRequest, true);
            app.TranslationpixelLabel = uilabel(app.AffineTransformationUIFigure);
            app.TranslationpixelLabel.Text = 'Translation (pixel)';
            app.ResizeratioLabel = uilabel(app.AffineTransformationUIFigure);
            app.ResizeratioLabel.Text = 'Resize (ratio)';
            app.ApplytooriginalButton = uibutton(app.AffineTransformationUIFigure, 'push');
            app.ApplytooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytooriginalButtonPushed, true);
            app.ApplytooriginalButton.Text = 'Apply to original';
            app.RotationEditField = uieditfield(app.AffineTransformationUIFigure, 'numeric');
            app.RotationEditField.Value = 15;
            app.RotationEditFieldLabel = uilabel(app.AffineTransformationUIFigure);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.XEditField = uieditfield(app.AffineTransformationUIFigure, 'numeric');
            app.XEditField.Value = 12;
            app.XEditFieldLabel = uilabel(app.AffineTransformationUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.YEditField = uieditfield(app.AffineTransformationUIFigure, 'numeric');
            app.YEditField.Value = 8;
            app.YEditFieldLabel = uilabel(app.AffineTransformationUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.xEditField = uieditfield(app.AffineTransformationUIFigure, 'numeric');
            app.xEditField.Value = 1.2;
            app.xEditFieldLabel = uilabel(app.AffineTransformationUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.yEditField = uieditfield(app.AffineTransformationUIFigure, 'numeric');
            app.yEditField.Value = 1.3;
            app.yEditFieldLabel = uilabel(app.AffineTransformationUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.CloseButton = uibutton(app.AffineTransformationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ApplytocurrentButton = uibutton(app.AffineTransformationUIFigure, 'push');
            app.ApplytocurrentButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytocurrentButtonPushed, true);
            app.ApplytocurrentButton.Text = '*Apply to current';
            app.UndoonceButton = uibutton(app.AffineTransformationUIFigure, 'push');
            app.UndoonceButton.ButtonPushedFcn = createCallbackFcn(app, @UndoonceButtonPushed, true);
            app.UndoonceButton.Text = 'Undo once';
            app.AddparamstoBatchProcessingButton = uibutton(app.AffineTransformationUIFigure, 'push');
            app.AddparamstoBatchProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @AddparamstoBatchProcessingButtonPushed, true);
            app.AddparamstoBatchProcessingButton.Text = 'Add params to Batch Processing';
            % Show the figure after all components are created
            app.AffineTransformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Affine(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AffineTransformationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AffineTransformationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AffineTransformationUIFigure)
        end
    end
end