classdef Batch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatchProcessingUIFigure      matlab.ui.Figure
        ApplytoUserInputmanyButton   matlab.ui.control.Button
        ApplytoDashboardSamplefromoriginalButton  matlab.ui.control.Button
        LoadfromMODfileButton        matlab.ui.control.Button
        SavetoMODfileButton          matlab.ui.control.Button
        AffinetransformationAFPanel  matlab.ui.container.Panel
        AFCheckBox                   matlab.ui.control.CheckBox
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        RotationEditFieldLabel       matlab.ui.control.Label
        RotationEditField            matlab.ui.control.NumericEditField
        ResizeratioLabel             matlab.ui.control.Label
        TranslationpixelLabel        matlab.ui.control.Label
        SpiculationSPPanel           matlab.ui.container.Panel
        HeightpixelEditFieldLabel    matlab.ui.control.Label
        HeightpixelEditField         matlab.ui.control.NumericEditField
        SPCheckBox                   matlab.ui.control.CheckBox
        WidthpixelEditField          matlab.ui.control.EditField
        WidthpixelEditFieldLabel     matlab.ui.control.Label
        AngleEditField               matlab.ui.control.EditField
        CenterLabel                  matlab.ui.control.Label
        FourierDescriptorFDPanel     matlab.ui.container.Panel
        FDCheckBox                   matlab.ui.control.CheckBox
        range_pLabel                 matlab.ui.control.Label
        DetailLabel                  matlab.ui.control.Label
        detail_pLabel                matlab.ui.control.Label
        TotalEditField               matlab.ui.control.EditField
        TotalEditFieldLabel          matlab.ui.control.Label
        TotalLabel                   matlab.ui.control.Label
        RangeEditField               matlab.ui.control.EditField
        RangeEditFieldLabel          matlab.ui.control.Label
        DetailEditField              matlab.ui.control.EditField
        DetailEditFieldLabel         matlab.ui.control.Label
        MagnitudeEditField           matlab.ui.control.EditField
        MagnitudeEditFieldLabel      matlab.ui.control.Label
        CopyButton                   matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        OverwriteButton              matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        ModificationsListBoxLabel    matlab.ui.control.Label
        ModificationsListBox         matlab.ui.control.ListBox
    end


    properties (Access = private)

        CallingApp   % Main app object

    end
    

    methods (Access = public)

        function refrashList(app)
            list = evalin("base","list");

            if isempty(fieldnames(list))
                return;
            else
                % Clean and re-add
                app.ModificationsListBox.Items={};

                n = length(list.row);
                for i=1:n

                    switch list.row(i).categ
                        case "FD"
                            app.ModificationsListBox.Items{i}=...
                                ['Operation: ',num2str(i),' ------------FD------------',newline, ...
                                'FD-Detail(%) = ',num2str(list.row(i).fd_detail_p),newline, ...
                                'FD-Range(%) = ',num2str(list.row(i).fd_range_p),newline,...
                                'FD-Magnitude = ',num2str(list.row(i).fd_mag)];

                        case "SP"
                            app.ModificationsListBox.Items{i}=...
                                ['Operation: ',num2str(i),' ------------SP------------',newline, ...
                                'SP-Center = ',num2str(list.row(i).sp_center),newline,...
                                'SP-Height = ',num2str(list.row(i).sp_height),newline,...
                                'SP-Width = ',num2str(list.row(i).sp_width)];

                        case "AF"
                            app.ModificationsListBox.Items{i}=...
                                ['Operation: ',num2str(i),' ------------AF------------',newline, ...
                                'AF-Resize(x,y) = ','(',num2str(list.row(i).af_resize_x),', ',...
                                num2str(list.row(i).af_resize_y),')',newline,...
                                'AF-Shift(X,Y) = ','(',num2str(list.row(i).af_shift_x),', ',...
                                num2str(list.row(i).af_shift_y),')',newline,...
                                'AF-Rotation = ',num2str(list.row(i).af_rotation)];
                    end

                end

            end

        end

        function color_flush(app,obj)

            for i=1:3
                obj.ForegroundColor="red";
                pause(0.1)
                obj.ForegroundColor="blue";
                pause(0.1)
                obj.ForegroundColor="black";
            end

        end

        % Apply MODs to a boundary
        function bd = ApplyMods(app, omask)
            list = evalin("base","list");

            try

                [B, ~]  = bwboundaries(omask);
                bd = B{1}; % take the first boundary

                codes = bd2Fdesc(omask,10); % init detail=10, just for get the max detail_value
                Max_value = (codes(end,1)-1)/2;

                if isempty(fieldnames(list))
                    return;
                else

                    n = length(list.row);
                    for i=1:n

                        switch list.row(i).categ
                            case "FD"
                                detail = round(list.row(i).fd_detail_p*Max_value/100);
                                range = round(list.row(i).fd_range_p*detail/100);
                                magnitude = list.row(i).fd_mag;

                                mask = poly2mask(bd(:,2), bd(:,1),size(omask,1),size(omask,2));
                                bd = mod_fourier(mask, detail, range, magnitude,true);

                            case "SP"
                                center = list.row(i).sp_center;
                                height = list.row(i).sp_height;
                                width = list.row(i).sp_width;

                                bd = mod_spike(bd, center, height, width, true);

                            case "AF"
                                resize = [list.row(i).af_resize_x, list.row(i).af_resize_y];
                                rotate = list.row(i).af_rotation;
                                shift = [list.row(i).af_shift_x,list.row(i).af_shift_y];

                                mask = poly2mask(bd(:,2), bd(:,1),size(omask,1),size(omask,2));
                                bd = mod_affine(mask, resize, rotate, shift, true);

                        end

                    end

                end

            catch ME
                disp (ME.message)
                bd = zeros(1,2); % return empty data
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            % Update UI with input values
            omask = evalin("base","omask");
            codes = bd2Fdesc(omask,10); % init detail=10, just for get the max detail_value
            app.TotalEditField.Value = num2str(floor((codes(end,1)-1)/2));

            app.detail_pLabel.Text = num2str((str2num(app.DetailEditField.Value) / ...
                str2num(app.TotalEditField.Value))*100);

            app.range_pLabel.Text = num2str((str2num(app.RangeEditField.Value) / ...
                str2num(app.DetailEditField.Value))*100);

            % init the list
            list = struct;
            assignin ("base","list",list)

            % init the check boxes
            app.FDCheckBox.Value=1;
            app.SPCheckBox.Value=0;
            app.AFCheckBox.Value=0;

            Batch_window_opening = true;
            assignin("base","Batch_window_opening",Batch_window_opening)

            % app.RemoveButton.Enable="off";
        end

        % Value changed function: DetailEditField
        function DetailEditFieldValueChanged(app, event)
            value = app.DetailEditField.Value;
            app.detail_pLabel.Text = num2str((str2num(value) / ...
                str2num(app.TotalEditField.Value))*100);
            app.range_pLabel.Text = num2str((str2num(app.RangeEditField.Value) / ...
                str2num(app.DetailEditField.Value))*100);
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            value = app.RangeEditField.Value;
            app.range_pLabel.Text = num2str((str2num(value) / ...
                str2num(app.DetailEditField.Value))*100);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            params = struct;

            if app.FDCheckBox.Value == 1
                params.categ = "FD";
                params.fd_detail_p = str2num(app.detail_pLabel.Text);
                params.fd_range_p = str2num(app.range_pLabel.Text);
                params.fd_mag = str2num(app.MagnitudeEditField.Value);

                params.sp_center = NaN;
                params.sp_height = NaN;
                params.sp_width = NaN;

                params.af_resize_x = NaN;
                params.af_resize_y = NaN;
                params.af_shift_x = NaN;
                params.af_shift_y = NaN;
                params.af_rotation = NaN;
            end

            if app.SPCheckBox.Value==1
                params.categ = "SP";
                params.sp_center = str2num(app.AngleEditField.Value);
                params.sp_height = app.HeightpixelEditField.Value;
                params.sp_width = str2num(app.WidthpixelEditField.Value);

                params.fd_detail_p = NaN;
                params.fd_range_p = NaN;
                params.fd_mag = NaN;

                params.af_resize_x = NaN;
                params.af_resize_y = NaN;
                params.af_shift_x = NaN;
                params.af_shift_y = NaN;
                params.af_rotation = NaN;
            end

            if app.AFCheckBox.Value==1
                params.categ = "AF";
                params.af_resize_x = app.xEditField.Value;
                params.af_resize_y = app.yEditField.Value;
                params.af_shift_x = app.XEditField.Value;
                params.af_shift_y = app.YEditField.Value;
                params.af_rotation = app.RotationEditField.Value;

                params.fd_detail_p = NaN;
                params.fd_range_p = NaN;
                params.fd_mag = NaN;

                params.sp_center = NaN;
                params.sp_height = NaN;
                params.sp_width = NaN;
            end

            list = evalin("base","list");
            % assignin ("base","params",params)

            % check if empty list
            if isempty(fieldnames(list))
                list.row(1) = params;
            else
                list.row(end+1) = params;
            end

            assignin ("base","list",list)
            refrashList(app)


        end

        % Close request function: BatchProcessingUIFigure
        function BatchProcessingUIFigureCloseRequest(app, event)
            Batch_window_opening = false;
            assignin("base","Batch_window_opening",Batch_window_opening)
            delete(app)

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if ~isempty(app.ModificationsListBox.ValueIndex)
                list = evalin("base","list");
                list.row(app.ModificationsListBox.ValueIndex) = [];
                assignin ("base","list",list)
                refrashList(app)
            end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            if ~isempty(app.ModificationsListBox.ValueIndex)
                list = evalin("base","list");
                params = list.row(app.ModificationsListBox.ValueIndex);

                switch params.categ
                    case "FD"
                        app.detail_pLabel.Text = num2str(params.fd_detail_p);
                        app.range_pLabel.Text = num2str(params.fd_range_p);
                        app.DetailEditField.Value = num2str(round(...
                            params.fd_detail_p*str2num(app.TotalEditField.Value)/100));
                        app.RangeEditField.Value = num2str(round(...
                            params.fd_range_p*str2num(app.DetailEditField.Value)/100));
                        app.MagnitudeEditField.Value = num2str(params.fd_mag);

                        % Highlight the changed categ
                        color_flush(app,app.FourierDescriptorFDPanel);


                    case "SP"
                        app.AngleEditField.Value = num2str(params.sp_center);
                        app.HeightpixelEditField.Value = params.sp_height;
                        app.WidthpixelEditField.Value = num2str(params.sp_width);

                        % Highlight the changed categ
                        color_flush(app,app.SpiculationSPPanel);

                    case "AF"
                        app.xEditField.Value = params.af_resize_x;
                        app.yEditField.Value = params.af_resize_y;
                        app.XEditField.Value = params.af_shift_x;
                        app.YEditField.Value = params.af_shift_y;
                        app.RotationEditField.Value = params.af_rotation;

                        % Highlight the changed categ
                        color_flush(app,app.AffinetransformationAFPanel);
                end


            end
        end

        % Button pushed function: OverwriteButton
        function OverwriteButtonPushed(app, event)
            if ~isempty(app.ModificationsListBox.ValueIndex)
                params = struct;

                if app.FDCheckBox.Value == 1
                    params.categ = "FD";
                    params.fd_detail_p = str2num(app.detail_pLabel.Text);
                    params.fd_range_p = str2num(app.range_pLabel.Text);
                    params.fd_mag = str2num(app.MagnitudeEditField.Value);

                    params.sp_center = NaN;
                    params.sp_height = NaN;
                    params.sp_width = NaN;

                    params.af_resize_x = NaN;
                    params.af_resize_y = NaN;
                    params.af_shift_x = NaN;
                    params.af_shift_y = NaN;
                    params.af_rotation = NaN;
                end

                if app.SPCheckBox.Value==1
                    params.categ = "SP";
                    params.sp_center = str2num(app.AngleEditField.Value);
                    params.sp_height = app.HeightpixelEditField.Value;
                    params.sp_width = str2num(app.WidthpixelEditField.Value);

                    params.fd_detail_p = NaN;
                    params.fd_range_p = NaN;
                    params.fd_mag = NaN;

                    params.af_resize_x = NaN;
                    params.af_resize_y = NaN;
                    params.af_shift_x = NaN;
                    params.af_shift_y = NaN;
                    params.af_rotation = NaN;
                end

                if app.AFCheckBox.Value==1
                    params.categ = "AF";
                    params.af_resize_x = app.xEditField.Value;
                    params.af_resize_y = app.yEditField.Value;
                    params.af_shift_x = app.XEditField.Value;
                    params.af_shift_y = app.YEditField.Value;
                    params.af_rotation = app.RotationEditField.Value;

                    params.fd_detail_p = NaN;
                    params.fd_range_p = NaN;
                    params.fd_mag = NaN;

                    params.sp_center = NaN;
                    params.sp_height = NaN;
                    params.sp_width = NaN;
                end

                list = evalin("base","list");
                list.row(app.ModificationsListBox.ValueIndex) = params;
                assignin ("base","list",list)
                refrashList(app)
            end
        end

        % Button pushed function: SavetoMODfileButton
        function SavetoMODfileButtonPushed(app, event)
            list = evalin("base","list");
            if ~isempty(fieldnames(list))
                writestruct(list,'Saved/saved_batch_processing_params.xml')
                uialert(app.BatchProcessingUIFigure, "File saved: /MISS-tool/Saved/saved_batch_processing_params.xml", ...
                    "Message","Icon","success");
            end
        end

        % Value changed function: FDCheckBox
        function FDCheckBoxValueChanged(app, event)
            if app.FDCheckBox.Value==0
                app.FDCheckBox.Value=1;
                app.SPCheckBox.Value=0;
                app.AFCheckBox.Value=0;
            else
                app.SPCheckBox.Value=0;
                app.AFCheckBox.Value=0;
            end

        end

        % Value changed function: SPCheckBox
        function SPCheckBoxValueChanged(app, event)
            if  app.SPCheckBox.Value==0
                app.FDCheckBox.Value=0;
                app.SPCheckBox.Value=1;
                app.AFCheckBox.Value=0;
            else
                app.FDCheckBox.Value=0;
                app.AFCheckBox.Value=0;
            end
        end

        % Value changed function: AFCheckBox
        function AFCheckBoxValueChanged(app, event)
            if  app.AFCheckBox.Value==0
                app.FDCheckBox.Value=0;
                app.SPCheckBox.Value=0;
                app.AFCheckBox.Value=1;
            else
                app.FDCheckBox.Value=0;
                app.SPCheckBox.Value=0;
            end
        end

        % Button pushed function: LoadfromMODfileButton
        function LoadfromMODfileButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.xml','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    list = readstruct([p f]); % path+filename
                    assignin ("base","list",list)
                    refrashList(app)

                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Button pushed function: ApplytoDashboardSamplefromoriginalButton
        function ApplytoDashboardSamplefromoriginalButtonPushed(app, event)
            % apply to original
            omask = evalin("base","omask");
            contour = ApplyMods(app,omask);

            if sum(contour(:))==0 % error happened
                uialert(app.BatchProcessingUIFigure, "Cannot generate mask, please use different params.", "Error");
            else

                % convert boundary to mask, to keep the first boundary
                new_msk = poly2mask(contour(:,2), contour(:,1),size(omask,1),size(omask,2));
                new_msk = imfill(new_msk,'holes');
                [B, ~]  = bwboundaries(new_msk);
                bd = B{1}; % the first boundary

                assignin("base","bd",bd);
                updateimage(app.CallingApp, bd)
            end

        end

        % Button pushed function: ApplytoUserInputmanyButton
        function ApplytoUserInputmanyButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [fileNames, filePath] = uigetfile(filterspec,'MultiSelect', 'on'); % to select multiple files
            % Make sure user didn't cancel uigetfile
            if (ischar(filePath))
                % Check if the user selected any files
                if iscell(fileNames)
                    SegFiles = {};
                    % User selected multiple files
                    for i = 1:length(fileNames)
                        SegFiles = [SegFiles; strcat(filePath, fileNames{i})];
                    end
                else
                    % User selected only one file
                    SegFiles = {[filePath, fileNames]};
                end
                disp(SegFiles);

                % Batch process and save
            f = waitbar(0,'Computing measures...');
            for i=1:size(SegFiles,1)
                % Seg mask
                imS = imread(SegFiles{i});
                if ~islogical(imS)
                    if size(imS,3)~=1
                        imS=rgb2gray(imS);
                    end
                    imS = imbinarize(imS);  % binary area image
                end

                % if error happened, return empty mask
                contour = ApplyMods(app,imS);

                % convert boundary to mask, to keep the first boundary
                new_msk = poly2mask(contour(:,2), contour(:,1),size(imS,1),size(imS,2));
                new_msk = imfill(new_msk,'holes');
                [B, ~]  = bwboundaries(new_msk);
                bd = B{1}; % the first boundary

                filled = poly2mask(bd(:,2), bd(:,1),size(imS,1),size(imS,2));

                if iscell(fileNames)
                    imwrite(filled,['Saved/Batch_saved/',fileNames{i}(1:end-4),'_syn.png'],"png");
                else % User selected only one file
                    imwrite(filled,['Saved/Batch_saved/',fileNames(1:end-4),'_syn.png'],"png");
                end

                waitbar(i/size(SegFiles,1),f,'Computing measures...');
            end

            close(f)
            uialert(app.BatchProcessingUIFigure, "Synthetic images saved at /MISS-tool/Saved/Batch_saved/.", ...
                    "Message","Icon","success");

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BatchProcessingUIFigure = uifigure('Visible', 'off');
            app.BatchProcessingUIFigure.Name = 'Batch Processing';
            app.BatchProcessingUIFigure.CloseRequestFcn = createCallbackFcn(app, @BatchProcessingUIFigureCloseRequest, true);
            app.ModificationsListBox = uilistbox(app.BatchProcessingUIFigure);
            app.ModificationsListBox.Items = {};
            app.ModificationsListBox.Value = {};
            app.ModificationsListBoxLabel = uilabel(app.BatchProcessingUIFigure);
            app.ModificationsListBoxLabel.Text = 'Modifications';
            app.AddButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '< Add';
            app.OverwriteButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.OverwriteButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteButtonPushed, true);
            app.OverwriteButton.Text = '< Overwrite';
            app.RemoveButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CopyButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy >';
            app.FourierDescriptorFDPanel = uipanel(app.BatchProcessingUIFigure);
            app.FourierDescriptorFDPanel.Title = '      Fourier Descriptor (FD)';
            app.MagnitudeEditFieldLabel = uilabel(app.FourierDescriptorFDPanel);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.FourierDescriptorFDPanel, 'text');
            app.MagnitudeEditField.InputType = 'digits';
            app.MagnitudeEditField.Value = '4';
            app.DetailEditFieldLabel = uilabel(app.FourierDescriptorFDPanel);
            app.DetailEditFieldLabel.Text = 'Detail #';
            app.DetailEditField = uieditfield(app.FourierDescriptorFDPanel, 'text');
            app.DetailEditField.InputType = 'digits';
            app.DetailEditField.ValueChangedFcn = createCallbackFcn(app, @DetailEditFieldValueChanged, true);
            app.DetailEditField.Value = '10';
            app.RangeEditFieldLabel = uilabel(app.FourierDescriptorFDPanel);
            app.RangeEditFieldLabel.Text = 'Range #';
            app.RangeEditField = uieditfield(app.FourierDescriptorFDPanel, 'text');
            app.RangeEditField.InputType = 'digits';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.RangeEditField.Value = '3';
            app.TotalLabel = uilabel(app.FourierDescriptorFDPanel);
            app.TotalLabel.Text = '/ Total # = (%)';
            app.TotalEditFieldLabel = uilabel(app.FourierDescriptorFDPanel);
            app.TotalEditFieldLabel.Text = 'Total #';
            app.TotalEditField = uieditfield(app.FourierDescriptorFDPanel, 'text');
            app.TotalEditField.InputType = 'digits';
            app.TotalEditField.Editable = 'off';
            app.TotalEditField.Enable = 'off';
            app.TotalEditField.Value = '555';
            app.detail_pLabel = uilabel(app.FourierDescriptorFDPanel);
            app.detail_pLabel.Text = 'detail_p';
            app.DetailLabel = uilabel(app.FourierDescriptorFDPanel);
            app.DetailLabel.Text = '/ Detail # = (%)';
            app.range_pLabel = uilabel(app.FourierDescriptorFDPanel);
            app.range_pLabel.Text = 'range_p';
            app.FDCheckBox = uicheckbox(app.FourierDescriptorFDPanel);
            app.FDCheckBox.ValueChangedFcn = createCallbackFcn(app, @FDCheckBoxValueChanged, true);
            app.FDCheckBox.Text = '';
            app.SpiculationSPPanel = uipanel(app.BatchProcessingUIFigure);
            app.SpiculationSPPanel.Title = '      Spiculation (SP)';
            app.CenterLabel = uilabel(app.SpiculationSPPanel);
            app.CenterLabel.Text = 'Angle°';
            app.AngleEditField = uieditfield(app.SpiculationSPPanel, 'text');
            app.AngleEditField.InputType = 'digits';
            app.AngleEditField.Value = '35';
            app.WidthpixelEditFieldLabel = uilabel(app.SpiculationSPPanel);
            app.WidthpixelEditFieldLabel.Text = 'Width  (pixel)';
            app.WidthpixelEditField = uieditfield(app.SpiculationSPPanel, 'text');
            app.WidthpixelEditField.InputType = 'digits';
            app.WidthpixelEditField.Value = '10';
            app.SPCheckBox = uicheckbox(app.SpiculationSPPanel);
            app.SPCheckBox.ValueChangedFcn = createCallbackFcn(app, @SPCheckBoxValueChanged, true);
            app.SPCheckBox.Text = '';
            app.HeightpixelEditField = uieditfield(app.SpiculationSPPanel, 'numeric');
            app.HeightpixelEditField.Value = 50;
            app.HeightpixelEditFieldLabel = uilabel(app.SpiculationSPPanel);
            app.HeightpixelEditFieldLabel.Text = 'Height  (pixel)';
            app.AffinetransformationAFPanel = uipanel(app.BatchProcessingUIFigure);
            app.AffinetransformationAFPanel.Title = '      Affine transformation (AF)';
            app.TranslationpixelLabel = uilabel(app.AffinetransformationAFPanel);
            app.TranslationpixelLabel.Text = 'Translation (pixel)';
            app.ResizeratioLabel = uilabel(app.AffinetransformationAFPanel);
            app.ResizeratioLabel.Text = 'Resize (ratio)';
            app.RotationEditField = uieditfield(app.AffinetransformationAFPanel, 'numeric');
            app.RotationEditField.Value = 15;
            app.RotationEditFieldLabel = uilabel(app.AffinetransformationAFPanel);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.XEditField = uieditfield(app.AffinetransformationAFPanel, 'numeric');
            app.XEditField.Value = 12;
            app.XEditFieldLabel = uilabel(app.AffinetransformationAFPanel);
            app.XEditFieldLabel.Text = 'X';
            app.YEditField = uieditfield(app.AffinetransformationAFPanel, 'numeric');
            app.YEditField.Value = 8;
            app.YEditFieldLabel = uilabel(app.AffinetransformationAFPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.xEditField = uieditfield(app.AffinetransformationAFPanel, 'numeric');
            app.xEditField.Value = 1.2;
            app.xEditFieldLabel = uilabel(app.AffinetransformationAFPanel);
            app.xEditFieldLabel.Text = 'x';
            app.yEditField = uieditfield(app.AffinetransformationAFPanel, 'numeric');
            app.yEditField.Value = 1.3;
            app.yEditFieldLabel = uilabel(app.AffinetransformationAFPanel);
            app.yEditFieldLabel.Text = 'y';
            app.AFCheckBox = uicheckbox(app.AffinetransformationAFPanel);
            app.AFCheckBox.ValueChangedFcn = createCallbackFcn(app, @AFCheckBoxValueChanged, true);
            app.AFCheckBox.Text = '';
            app.SavetoMODfileButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.SavetoMODfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoMODfileButtonPushed, true);
            app.SavetoMODfileButton.Text = 'Save to MOD file';
            app.LoadfromMODfileButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.LoadfromMODfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromMODfileButtonPushed, true);
            app.LoadfromMODfileButton.Text = 'Load from MOD file';
            app.ApplytoDashboardSamplefromoriginalButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.ApplytoDashboardSamplefromoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoDashboardSamplefromoriginalButtonPushed, true);
            app.ApplytoDashboardSamplefromoriginalButton.Text = 'Apply to Dashboard Sample (from original)';
            app.ApplytoUserInputmanyButton = uibutton(app.BatchProcessingUIFigure, 'push');
            app.ApplytoUserInputmanyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoUserInputmanyButtonPushed, true);
            app.ApplytoUserInputmanyButton.Text = 'Apply to User Input (many)';
            % Show the figure after all components are created
            app.BatchProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Batch(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BatchProcessingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BatchProcessingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatchProcessingUIFigure)
        end
    end
end