classdef Fourier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TotalEditField           matlab.ui.control.EditField
        TotalLabel               matlab.ui.control.Label
        AddparamstoBatchProcessingButton  matlab.ui.control.Button
        UndoonceButton           matlab.ui.control.Button
        ApplytocurrentButton     matlab.ui.control.Button
        ApplytooriginalButton    matlab.ui.control.Button
        MagnitudeEditField       matlab.ui.control.EditField
        MagnitudeEditFieldLabel  matlab.ui.control.Label
        RangeDropDown            matlab.ui.control.DropDown
        RangeDropDownLabel       matlab.ui.control.Label
        DetailSlider             matlab.ui.control.Slider
        DetailSliderLabel        matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        detail_value             matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp   % Main app object
    end

    methods (Access = private)

        % reset Range options
        function rf_level(app)

            old_RangeValue = app.RangeDropDown.Value;
            List = cellstr(string(0:app.detail_value.Value-1));
            app.RangeDropDown.Items=List;

            if str2num(old_RangeValue)>str2num(List{end})
                app.RangeDropDown.Value = List{end};
            else
                app.RangeDropDown.Value = old_RangeValue;
            end

        end

        % main function
        function FTgenerate(app,from_org)
            omask = evalin("base","omask");
            if from_org  % apply to original?
                mask = omask;
            else
                obd = evalin("base","obd");
                bd = evalin("base","bd");
                if isequal(bd,obd) % for original inputs in syn mode, no change
                    mask = omask; % use original mask
                else
                    mask = poly2mask(bd(:,2), bd(:,1),size(omask,1),size(omask,2));
                end
            end

            % Reset parameter: Detail and Range options
            codes = bd2Fdesc(mask,10); % ini detail=10
            Max_value = (codes(end,1)-1)/2;
            app.DetailSlider.Limits = [1, Max_value];

            if app.detail_value.Value>Max_value
                app.DetailSlider.Value = Max_value; % ini detail=10
                app.detail_value.Value = round(app.DetailSlider.Value);
            end
            rf_level(app)

            % ################# Apply FT ####################
            % codes = bd2Fdesc(mask,app.detail_value.Value);
            % 
            % Fcode = codes(1:end-1,:);
            % Len = size(Fcode,1);
            % 
            % n= str2num(app.RangeDropDown.Value);
            % k= str2num(app.MagnitudeEditField.Value);
            % 
            % for i=1:n
            %     rnd1 = (rand-0.5)*k;
            %     rnd2 = (rand-0.5)*k;
            %     Fcode(i,1)=Fcode(i,1)*(1+rnd1);
            %     Fcode(i,2)=Fcode(i,2)*(1+rnd2);
            % end
            % 
            % for j=Len:-1:(Len-n+1)
            %     rnd1 = (rand-0.5)*k;
            %     rnd2 = (rand-0.5)*k;
            %     Fcode(j,1)=Fcode(j,1)*(1+rnd1);
            %     Fcode(j,2)=Fcode(j,2)*(1+rnd2);
            % end
            % 
            % Fcode = [Fcode; codes(end,:)];
            % 
            % try
            %     re_img = Fdesc2bd(Fcode,size(mask));
            %     se = strel('disk',2);
            %     CH = imdilate(re_img,se);
            %     CH = imfill(CH,'holes');
            %     [B, ~]  = bwboundaries(CH);
            %     bd = B{1}; % take the first boundary
            % 
            % catch
            %     disp ("unexpected error")
            %     figure;imagesc(re_img);
            % end
            detail = app.detail_value.Value;
            range = str2num(app.RangeDropDown.Value);
            magnitude = str2num(app.MagnitudeEditField.Value);
            bd = mod_fourier(mask, detail, range, magnitude,true);

            % save last-time "bd" for _Undo_ if it exists
            try
                old_bd = evalin("base","bd");
                if ~isequal(old_bd,bd)
                    app.UndoonceButton.Enable = "on";
                    assignin("base","old_bd",old_bd); % public data
                end
            end

            assignin("base","bd",bd); % write CH as public data bd
            % #################################################

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % Update UI with input values
            omask = evalin("base","omask");
            codes = bd2Fdesc(omask,10); % init detail=10

            Max_value = floor((codes(end,1)-1)/2);
            app.TotalEditField.Value = num2str(Max_value);
            app.DetailSlider.Limits = [1, Max_value];
            app.DetailSlider.Value = 10; % init detail=10
            app.detail_value.Value = round(app.DetailSlider.Value);

            % init RangeDropDown
            List = cellstr(string(0:app.detail_value.Value-1));
            app.RangeDropDown.Items=List;
            app.RangeDropDown.Value=List{1};

            app.UndoonceButton.Enable="off";

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Delete the dialog box
            delete(app)

        end

        % Value changed function: DetailSlider
        function DetailSliderValueChanged(app, event)
            app.detail_value.Value = round(app.DetailSlider.Value);
            rf_level(app)
        end

        % Value changed function: detail_value
        function detail_valueValueChanged(app, event)
            app.DetailSlider.Value = app.detail_value.Value;
            rf_level(app)
        end

        % Button pushed function: ApplytooriginalButton
        function ApplytooriginalButtonPushed(app, event)
            FTgenerate(app,true)
            bd = evalin("base","bd");
            updateimage(app.CallingApp, bd)
        end

        % Value changed function: RangeDropDown
        function RangeDropDownValueChanged(app, event)
            % value = app.RangeDropDown.Value;

        end

        % Callback function
        function Output_FilledButtonPushed(app, event)


        end

        % Button pushed function: ApplytocurrentButton
        function ApplytocurrentButtonPushed(app, event)
            FTgenerate(app,false)
            bd = evalin("base","bd");
            updateimage(app.CallingApp, bd)
        end

        % Button pushed function: UndoonceButton
        function UndoonceButtonPushed(app, event)
            % Restore last-time "bd" if it exists
            try
                bd = evalin("base","old_bd");
                assignin("base","bd",bd); % public data
                updateimage(app.CallingApp, bd)
                app.UndoonceButton.Enable = "off";
            end
        end

        % Button pushed function: AddparamstoBatchProcessingButton
        function AddparamstoBatchProcessingButtonPushed(app, event)
                        % Check if the window is opening
            if evalin("base","Batch_window_opening")==true
                params = struct;
                params.categ = "FD";
                params.fd_detail_p = app.detail_value.Value/str2num(app.TotalEditField.Value)*100;
                params.fd_range_p = str2num(app.RangeDropDown.Value)/app.detail_value.Value*100;
                params.fd_mag = str2num(app.MagnitudeEditField.Value);

                params.sp_center = NaN;
                params.sp_height = NaN;
                params.sp_width = NaN;

                params.af_resize_x = NaN;
                params.af_resize_y = NaN;
                params.af_shift_x = NaN;
                params.af_shift_y = NaN;
                params.af_rotation = NaN;

                list = evalin("base","list");

                % check if empty list
                if isempty(fieldnames(list))
                    list.row(1) = params;
                else
                    list.row(end+1) = params;
                end

                assignin ("base","list",list)
                refrashList(app.CallingApp.BatchApp)

            else
                uialert(app.UIFigure, "The Batch Processing window is not opening", "Error");
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fourier Descriptor';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.detail_value = uieditfield(app.UIFigure, 'numeric');
            app.detail_value.Limits = [0 Inf];
            app.detail_value.ValueChangedFcn = createCallbackFcn(app, @detail_valueValueChanged, true);
            app.detail_value.Value = 35;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Close';
            app.DetailSliderLabel = uilabel(app.UIFigure);
            app.DetailSliderLabel.Text = 'Detail';
            app.DetailSlider = uislider(app.UIFigure);
            app.DetailSlider.ValueChangedFcn = createCallbackFcn(app, @DetailSliderValueChanged, true);
            app.RangeDropDownLabel = uilabel(app.UIFigure);
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.UIFigure);
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @RangeDropDownValueChanged, true);
            app.MagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.UIFigure, 'text');
            app.MagnitudeEditField.Value = '4';
            app.ApplytooriginalButton = uibutton(app.UIFigure, 'push');
            app.ApplytooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytooriginalButtonPushed, true);
            app.ApplytooriginalButton.Text = 'Apply to original';
            app.ApplytocurrentButton = uibutton(app.UIFigure, 'push');
            app.ApplytocurrentButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytocurrentButtonPushed, true);
            app.ApplytocurrentButton.Text = '*Apply to current';
            app.UndoonceButton = uibutton(app.UIFigure, 'push');
            app.UndoonceButton.ButtonPushedFcn = createCallbackFcn(app, @UndoonceButtonPushed, true);
            app.UndoonceButton.Text = 'Undo once';
            app.AddparamstoBatchProcessingButton = uibutton(app.UIFigure, 'push');
            app.AddparamstoBatchProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @AddparamstoBatchProcessingButtonPushed, true);
            app.AddparamstoBatchProcessingButton.Text = 'Add params to Batch Processing';
            app.TotalLabel = uilabel(app.UIFigure);
            app.TotalLabel.Text = '/ Total #';
            app.TotalEditField = uieditfield(app.UIFigure, 'text');
            app.TotalEditField.InputType = 'digits';
            app.TotalEditField.Editable = 'off';
            app.TotalEditField.Enable = 'off';
            app.TotalEditField.Value = '555';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fourier(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end