classdef synmask_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ComputeHausdorffCheckBox        matlab.ui.control.CheckBox
        UserInputMultiplecasesPanel     matlab.ui.container.Panel
        PerformanceMetricsButton        matlab.ui.control.Button
        SegmentationcontoursButton      matlab.ui.control.Button
        SegFileNumLabel                 matlab.ui.control.Label
        ReferencecontoursButton         matlab.ui.control.Button
        TruthFileNumLabel               matlab.ui.control.Label
        RecoverButton                   matlab.ui.control.Button
        BatchProcessingButton           matlab.ui.control.Button
        SynthesisToolsPanel             matlab.ui.container.Panel
        TruthMaskLabel                  matlab.ui.control.Label
        SpiculationButton               matlab.ui.control.Button
        AffineButton                    matlab.ui.control.Button
        FourierButton                   matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        ReturntomainwindowButton        matlab.ui.control.Button
        ModeLabel                       matlab.ui.control.Label
        SegmentationMetricsListBoxLabel  matlab.ui.control.Label
        SegmentationMetricsListBox      matlab.ui.control.ListBox
        DisplayinfilledcontourCheckBox  matlab.ui.control.CheckBox
        UserInputSinglecasePanel        matlab.ui.container.Panel
        SegmentationcontourButton       matlab.ui.control.Button
        SegFileLabel                    matlab.ui.control.Label
        ReferencecontourButton          matlab.ui.control.Button
        TruthFileLabel                  matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CallingApp
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
    end
    
    properties (Access = public)
        BatchApp % Description
    end

    methods (Access = private)

        % load Truth Mask
        function flag_res = load_mask (~, imagefile)
            im = imread(imagefile);
            if size(im,3)~=1
                im=rgb2gray(im);
            end
            omask = imbinarize(im);  % binary area image
            assignin("base","omask",omask); % public data: original mask

            [B, ~]  = bwboundaries(omask);
            if length(B)>1
                flag_res = false;
            else
                obd = B{1}; % take the first boundary
                assignin("base","obd",obd); % public data: original boundry
                flag_res = true;
            end

        end

        % load Segmentation contour
        function flag_res = input_seg (~, imagefile)
            im = imread(imagefile);
            if size(im,3)~=1
                im=rgb2gray(im);
            end

            mask = imbinarize(im);  % binary area image
            assignin("base","oseg",mask); % public data: orginal seg

            [B, ~]  = bwboundaries(mask);

            if length(B)>1
                flag_res = false;
            else
                bd = B{1}; % take the first boundary
                assignin("base","bd",bd); % public data: modified boundry
                flag_res = true;
            end

        end

        % load Reference contour
        function flag_res = input_truth (~, imagefile)
            im = imread(imagefile);
            if size(im,3)~=1
                im=rgb2gray(im);
            end
            omask = imbinarize(im);  % binary area image
            assignin("base","omask",omask); % public data: original mask

            [B, ~]  = bwboundaries(omask);
            if length(B)>1
                flag_res = false;
            else
                obd = B{1}; % take the first boundary
                assignin("base","obd",obd); % public data: original boundry
                flag_res = true;
            end

        end

        function output_cell = compare_1on1(app, TruthFiles, SegFiles)
            f = waitbar(0,'Computing measures...');
            for i=1:size(TruthFiles,1)
                % Truth mask
                imT = imread(TruthFiles{i});
                if ~islogical(imT)
                    if size(imT,3)~=1
                        imT=rgb2gray(imT);
                    end
                    imT = imbinarize(imT);  % binary area image
                end

                % Seg mask
                imS = imread(SegFiles{i});
                if ~islogical(imS)
                    if size(imS,3)~=1
                        imS=rgb2gray(imS);
                    end
                    imS = imbinarize(imS);  % binary area image
                end

                idx = seg_measures(imT, imS, true);

                if i==1 % init output_cell only in first loop
                    output_cell =  fieldnames(idx)';
                    output_cell =  ['Seg','Truth',output_cell];
                end

                one_row = struct2cell(idx)';
                one_row = [SegFiles{i},TruthFiles{i},one_row];

                output_cell = [output_cell; one_row];

                waitbar(i/size(TruthFiles,1),f,'Computing measures...');

            end
            close(f)
            %writecell(output_cell,'Saved/Metrics_Result.csv');
            %uialert(app.UIFigure, "Metrics result saved in /MISS-tool/Saved/Metrics_Result.csv", ...
            %        "Message","Icon","success");
        end


    end


    methods (Access = public)

        % Update showing plots and metrics values
        function updateimage(app, boundary)
            omask = evalin("base","omask"); % Original mask
            obd = evalin("base","obd"); % Original boundary (contour)

            % set color map for drawing contours/masks
            cmap = [0, 0, 0; ...   % Black for 0: background
                1, 0, 0; ...    % Blue for 1: orginal input
                0, 0, 1];       % Red for 2: synthetic
            colormap(app.ImageAxes,cmap);

            try
                % ############### Draw contours/masks ################
                imagesc(app.ImageAxes,uint8(omask));

                % plot the boundary
                hold( app.ImageAxes, 'on' )
                plot(app.ImageAxes,boundary(:,2), boundary(:,1), 'g', 'LineWidth', 1);

                % convert boundary to mask
                filled = poly2mask(boundary(:,2), boundary(:,1),size(omask,1),size(omask,2));

                % plot filled contours
                if app.DisplayinfilledcontourCheckBox.Value==true
                    % Try to change the color (red)
                    filled_show = uint8(filled);
                    filled_show(filled_show==1) = 2;
                    h = imagesc(app.ImageAxes,filled_show);
                    h.AlphaData = 0.5;
                    hold( app.ImageAxes, 'off' )
                end
                % ######################################################

                % for original inputs in syn mode, no change -> filled = omask
                if isequal(boundary,obd)
                    filled = omask; % use original mask to compute metrics
                    %                     dice_value=1;
                    %                     FN = 0; FP = 0;
                    %                     jacc=1;
                end

                % in metrics mode, no change -> filled = oseg
                if app.ModeLabel.Text == "Metrics Computation"
                    oseg = evalin("base","oseg");
                    filled = oseg; % use original seg to compute metrics
                end

                assignin("base","filled",filled); % generated mask (filled modified contour)

                % % Compute Hausdorff Distances (slower)
                % f = waitbar(0,'Computing measures...');
                % waitbar(0,f,'Computing Hausdorff Distance...');
                % waitbar(0.5,f,'Computing Average Hausdorff Distance...');
                % waitbar(0.9,f,'Computing other measures...');
                % close(f)

                % ############### Segmentation Evaluation ################
                f = waitbar(0.5,'Computing measures...');
                idx = seg_measures(omask, filled, app.ComputeHausdorffCheckBox.Value);

                % display metrics values
                app.SegmentationMetricsListBox.Items = {['Dice: ',num2str(idx.Dice)], ['Jaccard: ',num2str(idx.Jaccard)],...
                    ['MSI: ',num2str(idx.MSI)], ...
                    ['FN: ',num2str(idx.FN)],['FP: ',num2str(idx.FP)],['TN: ',num2str(idx.TN)],['TP: ',num2str(idx.TP)],...
                    ['Recall/Sensitivity (TPR): ',num2str(idx.Recall_Sensitivity_TPR)],...
                    ['Specificity (TNR): ',num2str(idx.Specificity_TNR)],...
                    ['Fallout (FPR): ',num2str(idx.Fallout_FPR)],...
                    ['Precision (PPV): ',num2str(idx.Precision_PPV)],['Accuracy (ACC): ',num2str(idx.Accuracy_ACC)],...
                    ['AUC: ',num2str(idx.AUC)],['KAP: ',num2str(idx.KAP)],...
                    ['ARI: ',num2str(idx.ARI)],['ICC: ',num2str(idx.ICC)],['VS: ',num2str(idx.VS)],...
                    ['MI: ',num2str(idx.MI)],['VOI: ',num2str(idx.VOI)],['PBD: ',num2str(idx.PBD)],...
                    ['GCE: ',num2str(idx.GCE)],['HD: ',num2str(idx.HD)],['AVD: ',num2str(idx.AVD)],...
                    ['MHD: ',num2str(idx.MHD)]};

                % output metrics values
                assignin("base","metrics_value",idx); % output data

                close(f)

            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                close(f)
                return;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
            app.CallingApp = mainapp;
            app.ModeLabel.Text = mode;

            % Configure image axes
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            Batch_window_opening = false;
            assignin("base","Batch_window_opening",Batch_window_opening)

            % avoid delete(Fourier(app)) error
            omask = imbinarize(imread("truth_mask.png"));  % binary area image
            assignin("base","omask",omask); % public data: original mask

            % Two modes
            if mode=="Synthesis of Contour"
                app.UserInputSinglecasePanel.Visible = "off";
                app.UserInputMultiplecasesPanel.Visible="off";
                app.SynthesisToolsPanel.Visible = "on";
                app.SaveButton.Text = "Save syn mask & metrics values";
                app.BatchProcessingButton.Visible = "on";
                app.RecoverButton.Visible="on";
                
                app.BatchProcessingButton.Enable = "off";
                app.AffineButton.Enable = "off";
                app.SpiculationButton.Enable = "off";
                app.FourierButton.Enable = "off";
                app.RecoverButton.Enable = "off";

                app.ComputeHausdorffCheckBox.Value = false;
                % app.SegmentationcontourButton.Visible=false;
                % app.ReferencecontourButton.Visible=false;
                % app.SegFileLabel.Visible=false;
                % app.TruthFileLabel.Visible=false;
                % app.LoadButton.Visible=true;
                % app.SpiculationButton.Visible=true;
                % app.FourierButton.Visible=true;
                % app.AffineButton.Visible=true;
                % app.TruthMaskLabel.Visible = true;
                % app.TruthMaskLabel.Text = "";


            elseif mode=="Metrics Computation"
                app.UserInputSinglecasePanel.Visible = "on";
                app.UserInputMultiplecasesPanel.Visible="on";
                app.SynthesisToolsPanel.Visible = "off";
                app.SaveButton.Text = "Save metrics values";
                app.BatchProcessingButton.Visible = "off";
                app.RecoverButton.Visible="off";

                app.ComputeHausdorffCheckBox.Value = true;

                % app.SegmentationcontourButton.Visible=true;
                % app.ReferencecontourButton.Visible=true;
                % app.SegFileLabel.Visible=true;
                % app.TruthFileLabel.Visible=true;
                % app.SegFileLabel.Text="";
                % app.TruthFileLabel.Text="";
                % app.LoadButton.Visible=false;
                % app.SpiculationButton.Visible=false;
                % app.FourierButton.Visible=false;
                % app.AffineButton.Visible=false;
                % app.TruthMaskLabel.Visible = false;


            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    fname = [p f];
                    flag_res = load_mask(app, fname);

                    if flag_res==true
                        app.TruthMaskLabel.Text = f;
                        % if app.TruthMaskLabel.Text ~= ""
                        bd = evalin("base","obd");
                        assignin("base","bd",bd); % public data
                        app.BatchProcessingButton.Enable = "on";
                        app.BatchProcessingButton.Enable = "on";
                        app.AffineButton.Enable = "on";
                        app.SpiculationButton.Enable = "on";
                        app.FourierButton.Enable = "on";
                        app.RecoverButton.Enable = "on";
                        updateimage(app, bd)
                        % end
                    else
                        uialert(app.UIFigure,'Recent can handle only one individual component!','Function Warning',"CloseFcn",@(h,e)app.LoadButtonPushed)
                    end
                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: DisplayinfilledcontourCheckBox
        function DisplayinfilledcontourCheckBoxValueChanged(app, event)
            bd = evalin("base","bd");
            updateimage(app, bd)
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            omask = evalin("base","omask");
            assignin("base","omask",omask);

            % Disable Plot Options button while dialog is open
            % app.UIFigure.Visible = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp = Fourier(app);
        end

        % Button pushed function: AffineButton
        function AffineButtonPushed(app, event)
            app.DialogApp = Affine(app);
        end

        % Button pushed function: SpiculationButton
        function SpiculationButtonPushed(app, event)
            app.DialogApp = Spiculation(app);
        end

        % Callback function
        function ComputeHausdorffCheckBoxValueChanged(app, event)

        end

        % Button pushed function: SegmentationcontourButton
        function SegmentationcontourButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    fname = [p f];
                    flag_res = input_seg(app, fname);

                    if flag_res==true
                        app.SegFileLabel.Text=f;

                        if app.SegFileLabel.Text ~= "" && app.TruthFileLabel.Text ~=""
                            bd = evalin("base","bd"); % modified boundry
                            updateimage(app, bd)
                        end
                    else
                        uialert(app.UIFigure,'Recent can handle only one individual component!','Function Warning',"CloseFcn",@(h,e)app.LoadButtonPushed)
                    end
                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Button pushed function: ReferencecontourButton
        function ReferencecontourButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    fname = [p f];
                    flag_res = input_truth(app, fname);

                    if flag_res==true
                        app.TruthFileLabel.Text=f;

                        if app.SegFileLabel.Text ~= "" && app.TruthFileLabel.Text ~=""
                            bd = evalin("base","bd"); % modified boundry
                            updateimage(app, bd)
                        end
                    else
                        uialert(app.UIFigure,'Recent can handle only one individual component!','Function Warning',"CloseFcn",@(h,e)app.LoadButtonPushed)
                    end
                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Button pushed function: ReturntomainwindowButton
        function ReturntomainwindowButtonPushed(app, event)

            % Check if other windows are closed
            if evalin("base","Batch_window_opening")==true
                uialert(app.UIFigure, "Please close the Batch Processing window", "Error");
                return
            else
                app.DialogApp = main_option();
                delete(Affine(app))
                delete(Fourier(app))
                delete(Spiculation(app))
                delete(app)            
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Open help doc (htm)
            web("User Guide.htm#main-opt")
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
            metrics_value = evalin("base","metrics_value");
            filled = evalin("base","filled");

            % Save syn seg and metrics values
            switch app.ModeLabel.Text
                case "Metrics Computation" % Metric mode
                    Add_FileName = struct;
                    Add_FileName.Seg = app.SegFileLabel.Text;
                    Add_FileName.Ref = app.TruthFileLabel.Text;
                    Output_table = [struct2table(Add_FileName),struct2table(metrics_value)];
                    [file,location] = uiputfile('*.csv');
                    if file ~= 0
                        writetable(Output_table, ...
                            [location, file]);
                        uialert(app.UIFigure, ["Metrics result saved at",file],...
                            "Message","Icon","success");
                    else
                        uialert(app.UIFigure, "Metrics result NOT saved.", ...
                                    "Message","Icon","none");
                    end


                case "Synthesis of Contour" % Syn mode
                    [file,location] = uiputfile('*.png');
                    if file ~= 0
                        writetable(struct2table(metrics_value), ...
                            [location,file(1:end-4),'_Metrics.csv']);
                        imwrite(filled,[location,file],"png");
                        uialert(app.UIFigure, "Synthetic contour and its metrics result cagainst the truth mask saved.", ...
                                    "Message","Icon","success");
                    else
                        uialert(app.UIFigure, "Synthetic contour and its metrics result NOT saved.", ...
                                    "Message","Icon","none");
                    end

            end

            catch ME
                uialert(app.UIFigure, ME.message, 'Image Error');
            end
        end

        % Button pushed function: SegmentationcontoursButton
        function SegmentationcontoursButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [fileNames, filePath] = uigetfile(filterspec,'MultiSelect', 'on'); % to select multiple files
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile
            if (ischar(filePath))
                % Check if the user selected any files
                if iscell(fileNames)
                    SegFiles = {};
                    % User selected multiple files
                    for i = 1:length(fileNames)
                        SegFiles = [SegFiles; strcat(filePath, fileNames{i})];
                    end
                else
                    % User selected only one file
                    SegFiles = {[filePath, fileNames]};
                end
                disp(SegFiles);
                app.SegFileNumLabel.Text = ['Seg files: ',num2str(size(SegFiles,1))];
                assignin("base","SegFiles",SegFiles)
            end
        end

        % Button pushed function: ReferencecontoursButton
        function ReferencecontoursButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [fileNames, filePath] = uigetfile(filterspec,'MultiSelect', 'on'); % to select multiple files
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile
            if (ischar(filePath))
                % Check if the user selected any files
                if iscell(fileNames)
                    TruthFiles = {};
                    % User selected multiple files
                    for i = 1:length(fileNames)
                        TruthFiles = [TruthFiles; strcat(filePath, fileNames{i})];
                    end
                else
                    % User selected only one file
                    TruthFiles = {[filePath, fileNames]};
                end
                disp(TruthFiles);
                app.TruthFileNumLabel.Text = ['Turth files: ',num2str(size(TruthFiles,1))];
                assignin("base","TruthFiles",TruthFiles)
            end
        end

        % Button pushed function: PerformanceMetricsButton
        function PerformanceMetricsButtonPushed(app, event)
            % Check file lists for Seg and Truth
            try
                TruthFiles = evalin('base','TruthFiles');
                SegFiles = evalin('base','SegFiles');
            catch
                uialert(app.UIFigure, "User input files are missing", "Error");
                return;
            end

            % Check same # of files for Seg and Truth
            if size(TruthFiles,1)~=size(SegFiles,1)
                uialert(app.UIFigure, "Seg and Truth files do not match.", "Error");
                return;
            end

            output_cell = compare_1on1(app, TruthFiles, SegFiles);
            [file,location] = uiputfile('*.csv');
            if file ~= 0
                writecell(output_cell, [location, file]);
                uialert(app.UIFigure, ["Metrics result saved at",file],...
                            "Message","Icon","success");
            else
                uialert(app.UIFigure, ["Metrics result NOT saved",file],...
                            "Message","Icon","none");
            end
            
        end

        % Callback function
        function comparing_oldButtonPushed(app, event)
            try
                TruthFiles = evalin('base','TruthFiles');
                SegFiles = evalin('base','SegFiles');
            catch
                uialert(app.UIFigure, "User input files are missing", "Error");
                return;
            end

            f = waitbar(0,'Computing measures...');
            Ni = size(TruthFiles,1);
            Nj = size(SegFiles,1);

            for i=1:Ni
                for j = 1:Nj
                    % Truth mask
                    imT = imread(TruthFiles{i});
                    if ~islogical(imT)
                        if size(imT,3)~=1
                            imT=rgb2gray(imT);
                        end
                        imT = imbinarize(imT);  % binary area image
                    end

                    % Seg mask
                    imS = imread(SegFiles{j});
                    if ~islogical(imS)
                        if size(imS,3)~=1
                            imS=rgb2gray(imS);
                        end
                        imS = imbinarize(imS);  % binary area image
                    end

                    idx = seg_measures(imT, imS, true);

                    if i==1 && j==1 % init output_cell only in first loop
                        output_cell =  fieldnames(idx)';
                        output_cell =  ['Seg','Truth',output_cell];
                    end

                    one_row = struct2cell(idx)';
                    one_row = [SegFiles{j},TruthFiles{i},one_row];

                    output_cell = [output_cell; one_row];

                    waitbar( ((i-1)*Nj+j) / (Ni*Nj), f, 'Computing measures...' );

                end
            end
            close(f)
            writecell(output_cell,'Saved/Result_Metrics.csv');
            uialert(app.UIFigure, "Metrics result saved in /MISS-tool/Saved/Result_Metrics.csv", ...
                    "Message","Icon","success");

        end

        % Button pushed function: BatchProcessingButton
        function BatchProcessingButtonPushed(app, event)
            % Check if omask was loaded
            Err_flag = false;

            try
                omask = evalin("base","omask");
            catch
                uialert(app.UIFigure, "Please load the truth mask first", "Error");
                Err_flag = true;
            end

            if Err_flag == false
                app.BatchApp = Batch(app);
            end
        end

        % Button pushed function: RecoverButton
        function RecoverButtonPushed(app, event)
            bd = evalin("base","obd");
            assignin("base","bd",bd); % public data
            updateimage(app, bd)
        end

        % Callback function
        function AdvancedButtonPushed(app, event)

            % Check file lists for Seg and Truth
            try
                TruthFiles = evalin('base','TruthFiles');
                SegFiles = evalin('base','SegFiles');
            catch
                check_input = uiconfirm(app.UIFigure, "User input files are missing, " + ...
                    "but files list for comparing will be created. " + ...
                    "Please edit it to fulfill the requirement: " + ...
                    "the numbers of Seg and Truth files are same and non-zero.", ...
                    "Input incomplete","Icon","warning");
                if check_input == "Cancel" % cancel to end
                    return
                end
            end

            % OK to continue
            try
                TruthFiles = evalin('base','TruthFiles');
            catch
                TruthFiles = {};
            end

            try
                SegFiles = evalin('base','SegFiles');
            catch
                SegFiles = {};
            end

            selection_msg = ["Click #Save the list# to save the files list for comparing in #compare_files_list.csv#, " + ...
                "but if #compare_files_list.csv# exists, it could be overwritten.",...
                 "Column#1: Seg Files","Column#2: Truth Files",...
                "You can edit the files list after saving.","", ...
                "Click #To compare# button to compute and save metrics result, according to the files list."];
            selection = uiconfirm(app.UIFigure,selection_msg, ...
                "Advanced comparing", ...
                "Options",["To compare","Save the list","Cancel"], ...
                "DefaultOption",3,"CancelOption",3, 'Icon','info');


            switch selection
                case 'Save the list'
                    if isfile('Saved/compare_files_list.csv') % File exists.
                        check_input = uiconfirm(app.UIFigure, "The #compare_files_list.csv# exists, overwrite?", ...
                            "File exists","DefaultOption",2,"Icon","warning");
                        if check_input == "Cancel" % cancel to end
                            return
                        end
                    end
                    % OK to continue
                    cell_len = max(size(TruthFiles,1),size(SegFiles,1)); % max cell length

                    out_cell = cell(cell_len,2);
                    out_cell(1:size(SegFiles,1),1) = SegFiles;
                    out_cell(1:size(TruthFiles,1),2) = TruthFiles;
                    
                    writecell(out_cell,'Saved/compare_files_list.csv');
                    uialert(app.UIFigure, "Comparing files list saved in /MISS-tool/Saved/compare_files_list.csv", ...
                    "Message","Icon","success");
                    

                case 'To compare'
                    % Load files list for Seg and Truth
                    try
                        input_cell = readcell('Saved/compare_files_list.csv',"Delimiter",",");

                        SegFiles = input_cell(:,1);
                        SegFiles= cellfun(@rmmissing,SegFiles,'UniformOutput',false);
                        SegFiles(cellfun('isempty',SegFiles))=[];% delete missing rows

                        TruthFiles = input_cell(:,2);
                        TruthFiles= cellfun(@rmmissing,TruthFiles,'UniformOutput',false);
                        TruthFiles(cellfun('isempty',TruthFiles))=[]; % delete missing rows

                    catch ME
                        uialert(app.UIFigure, ME.message, "Error");
                        return
                    end

                    % Check same # of files for Seg and Truth
                    if size(TruthFiles,1)~=size(SegFiles,1)
                        uialert(app.UIFigure, ["Seg and Truth files do not match for 1-on-1 comparison.", ...
                            "please check the numbers of Seg and Truth files are same (number of columns) and non-zero in the file #compare_files_list.csv#."], "Error");
                        return
                    end

                    compare_1on1(app, TruthFiles, SegFiles);

            end

        end

        % Value changed function: ComputeHausdorffCheckBox
        function ComputeHausdorffCheckBoxValueChanged2(app, event)
            try
                bd = evalin("base","bd"); % modified boundry
                updateimage(app, bd)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Viewer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.UserInputSinglecasePanel = uipanel(app.UIFigure);
            app.UserInputSinglecasePanel.Title = 'User Input (Single case)';
            app.TruthFileLabel = uilabel(app.UserInputSinglecasePanel);
            app.TruthFileLabel.Text = 'Truth File';
            app.ReferencecontourButton = uibutton(app.UserInputSinglecasePanel, 'push');
            app.ReferencecontourButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencecontourButtonPushed, true);
            app.ReferencecontourButton.Text = 'Reference contour';
            app.SegFileLabel = uilabel(app.UserInputSinglecasePanel);
            app.SegFileLabel.Text = 'Seg File';
            app.SegmentationcontourButton = uibutton(app.UserInputSinglecasePanel, 'push');
            app.SegmentationcontourButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationcontourButtonPushed, true);
            app.SegmentationcontourButton.Text = 'Segmentation contour';
            app.DisplayinfilledcontourCheckBox = uicheckbox(app.UIFigure);
            app.DisplayinfilledcontourCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayinfilledcontourCheckBoxValueChanged, true);
            app.DisplayinfilledcontourCheckBox.Text = 'Display in filled contour';
            app.SegmentationMetricsListBox = uilistbox(app.UIFigure);
            app.SegmentationMetricsListBox.Items = {''};
            app.SegmentationMetricsListBox.Value = {};
            app.SegmentationMetricsListBoxLabel = uilabel(app.UIFigure);
            app.SegmentationMetricsListBoxLabel.Text = 'Segmentation Metrics';
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode';
            app.ReturntomainwindowButton = uibutton(app.UIFigure, 'push');
            app.ReturntomainwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomainwindowButtonPushed, true);
            app.ReturntomainwindowButton.Text = 'Return to main window';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SynthesisToolsPanel = uipanel(app.UIFigure);
            app.SynthesisToolsPanel.Title = 'Synthesis Tools';
            app.LoadButton = uibutton(app.SynthesisToolsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Truth Mask';
            app.FourierButton = uibutton(app.SynthesisToolsPanel, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.AffineButton = uibutton(app.SynthesisToolsPanel, 'push');
            app.AffineButton.ButtonPushedFcn = createCallbackFcn(app, @AffineButtonPushed, true);
            app.AffineButton.Text = 'Affine';
            app.SpiculationButton = uibutton(app.SynthesisToolsPanel, 'push');
            app.SpiculationButton.ButtonPushedFcn = createCallbackFcn(app, @SpiculationButtonPushed, true);
            app.SpiculationButton.Text = 'Spiculation';
            app.TruthMaskLabel = uilabel(app.SynthesisToolsPanel);
            app.TruthMaskLabel.Text = 'TruthMask';
            app.BatchProcessingButton = uibutton(app.UIFigure, 'push');
            app.BatchProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessingButtonPushed, true);
            app.BatchProcessingButton.Text = 'Batch Processing';
            app.RecoverButton = uibutton(app.UIFigure, 'push');
            app.RecoverButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverButtonPushed, true);
            app.RecoverButton.Text = 'Recover';
            app.UserInputMultiplecasesPanel = uipanel(app.UIFigure);
            app.UserInputMultiplecasesPanel.Title = 'User Input (Multiple cases)';
            app.TruthFileNumLabel = uilabel(app.UserInputMultiplecasesPanel);
            app.TruthFileNumLabel.Text = 'Truth File Num';
            app.ReferencecontoursButton = uibutton(app.UserInputMultiplecasesPanel, 'push');
            app.ReferencecontoursButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencecontoursButtonPushed, true);
            app.ReferencecontoursButton.Text = 'Reference contours';
            app.SegFileNumLabel = uilabel(app.UserInputMultiplecasesPanel);
            app.SegFileNumLabel.Text = 'Seg File Num';
            app.SegmentationcontoursButton = uibutton(app.UserInputMultiplecasesPanel, 'push');
            app.SegmentationcontoursButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationcontoursButtonPushed, true);
            app.SegmentationcontoursButton.Text = 'Segmentation contours';
            app.PerformanceMetricsButton = uibutton(app.UserInputMultiplecasesPanel, 'push');
            app.PerformanceMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @PerformanceMetricsButtonPushed, true);
            app.PerformanceMetricsButton.Text = 'Performance Metrics';
            app.ComputeHausdorffCheckBox = uicheckbox(app.UIFigure);
            app.ComputeHausdorffCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputeHausdorffCheckBoxValueChanged2, true);
            app.ComputeHausdorffCheckBox.Text = 'Compute Hausdorff Distances (slower)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synmask_win(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end